classdef M3AnalysisTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        ScoresEditFieldLabel       matlab.ui.control.Label
        ScoresEditField            matlab.ui.control.EditField
        TimesEditField             matlab.ui.control.EditField
        TimesEditFieldLabel        matlab.ui.control.Label
        KnownScoreEditField        matlab.ui.control.NumericEditField
        KnownScoreEditFieldLabel   matlab.ui.control.Label
        KnownTimeEditField         matlab.ui.control.NumericEditField
        KnownTimeEditFieldLabel    matlab.ui.control.Label
        KnownLengthEditField       matlab.ui.control.NumericEditField
        KnownLengthEditFieldLabel  matlab.ui.control.Label
        M3AnalysisToolLabel        matlab.ui.control.Label
        HowtouseLabel              matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        LengthWeightSlider         matlab.ui.control.Slider
        LengthWeightSliderLabel    matlab.ui.control.Label
        SpeedWeightSlider          matlab.ui.control.Slider
        SpeedWeightSliderLabel     matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)

        function inverseList = inverse(~, list)
        % Turns a list of numbers into a list of their inverses
            inverseList = zeros(1, length(list));
            for i = 1:length(list)
                inverseList(i) = 1 / list(i);
            end
        end
        
        function maxList = maxPair(~, cellArr)
        % Returns the pair with the maximum ratio
            maxList = cellArr{1};
            maxRatio = maxList(1) / maxList(2);
            for i = 1:length(cellArr)
                tempList = cellArr{i};
                tempRatio = tempList(1) / tempList(2);
                if tempRatio > maxRatio
                    maxList = tempList;
                    maxRatio = tempRatio;
                end
            end
        end

        function cellArr = rmvElement(~, cellArr, element)
        % Returns a cell array with the specified element removed
            index = find(cellfun(@(e) isequal(e, element), cellArr));
            if ~isempty(index)
                cellArr(index) = [];
            end
        end

        function numList = strToNums(~, str)
        % Turns a comma-seperated string of numbers into a list of doubles
            numStrs = split(str, ",");
            numList = str2double(numStrs);
        end
        
        function pairList = pair(~, list1, list2)
        %   Turns 2 lists into 1 list of index pairs
        %   e.g. pair([1, 2, 3], [4, 5, 6]) = {[1, 4], [2, 5], [3, 6]}
            if ~isvector(list1) || ~isvector(list2) || numel(list1) ~= numel(list2)
                error("Inputs must be vectors of the same length");
            end
            
            pairList = cell(1, length(list1));
            for i = 1:length(list1)
                pairList{i} = [list1(i), list2(i)];
            end
        end
        
        function [list1, list2] = unpair(~, pairList)
        % Turns a list of index pairs into 2 separate lists
        % e.g. unpair({[1, 4], [2, 5], [3, 6]}) = {[1, 2, 3], [4, 5, 6]}
            n = length(pairList);
            list1 = zeros(1, n);
            list2 = zeros(1, n);
            for i = 1:n
                pair = pairList{i};
                list1(i) = pair(1);
                list2(i) = pair(2);
            end
        end

        function inferredList = inferLengths(~, timeScoreList, knownCombo)
        %   Returns a list of inferred antenna lengths
        %   Input should be a list of time-score pairs and a known length-time-score combo
            if ~isvector(knownCombo) || numel(knownCombo) ~= 3
                error("Invalid known variable(s)")
            end
        
            knownTime = knownCombo(1);
            knownLength = knownCombo(2);
            knownScore = knownCombo(3);
        
            inferredList = zeros(1, length(timeScoreList));
            for i = 1:length(timeScoreList)
                tempPair = timeScoreList{i};
                tempTime = tempPair(1);
                tempScore = tempPair(2);
                tempLength = (tempScore - 2) / (knownScore - 2) * (knownLength / knownTime) * tempTime;
                inferredList(i) = tempLength;
            end
        end      

        function updatePlot(app)
            if app.KnownTimeEditField.Value <= 0 || app.KnownLengthEditField.Value <= 0 || app.KnownScoreEditField.Value <= 2 ...
               || app.TimesEditField.Value == "" || app.ScoresEditField.Value == ""
                return
            end

            knownCombo = [app.KnownTimeEditField.Value, app.KnownLengthEditField.Value, app.KnownScoreEditField.Value];
            knownTime = knownCombo(1);
            knownSpeed = 1 / knownTime;
            knownLength = knownCombo(2);
            knownScore = knownCombo(3);

            times = strToNums(app, string(app.TimesEditField.Value));
            scores = strToNums(app, string(app.ScoresEditField.Value));

            if length(times) ~= length(scores)
                return
            end

            timeScoreList = pair(app, times, scores);
            speeds = inverse(app, times);
            lengths = inferLengths(app, timeScoreList, knownCombo);

            xMin = min(speeds) * 0.80;
            xMax = max(speeds) * 1.20;
            xlim(app.UIAxes,[xMin, xMax]);
            yMin = min(lengths) * 0.80;
            yMax = max(lengths) * 1.20;
            ylim(app.UIAxes, [yMin, yMax]);
        
            bestPair = maxPair(app, pair(app, lengths, times));
            bestTime = bestPair(2);
            bestSpeed = 1 / bestTime;
            bestLength = bestPair(1);

            % Remove known time-score pair if it is in the input
            timeScoreList = rmvElement(app, timeScoreList, [knownTime, knownScore]);
            % Removes best time-score pair
            timeScoreList = rmvElement(app, timeScoreList, [bestTime, 3]);
            [times, ~] = unpair(app, timeScoreList);

            speeds = inverse(app, times);
            lengths = inferLengths(app, timeScoreList, knownCombo);
            % Plot competitor performance
            plot(app.UIAxes, speeds, lengths, "o", "MarkerSize", 8, "MarkerFaceColor", "black");
            % Plot our own performance
            hold(app.UIAxes, "on");
            plot(app.UIAxes, knownSpeed, knownLength, "o", "MarkerSize", 8, "MarkerFaceColor", "blue");
            % Plot the best performance
            hold(app.UIAxes, "on");
            plot(app.UIAxes, bestSpeed, bestLength, "o", "MarkerSize", 8, "MarkerFaceColor", "green");
            % Plot best performance boundary
            x = linspace(xMin, xMax);
            scale = (yMax - yMin) / (xMax - xMin);
            y = -scale * x + (bestLength + scale * bestSpeed);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, x, y, "LineWidth", 2, "Color", "green");
            % Plot line from our performance to best performance boundary
            % FIX THE REST OF THIS
            inputValue = 100;
            if inputValue <= 50
                newSlope = (1 / scale) * (inputValue / 50);
            else
                newSlope = (1 / scale) + tan((pi / 2) * ((inputValue - 50) / 50));
            end
            newY = newSlope * x + (knownLength - newSlope * knownSpeed);
            hold(app.UIAxes, "on");
            plot(app.UIAxes, x, newY, "LineWidth", 2, "Color", "blue");
            hold(app.UIAxes, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SpeedWeightSlider
        function updatedSpeedWeight(app, event)
            updatePlot(app);
        end

        % Value changed function: LengthWeightSlider
        function updatedLengthWeight(app, event)
            updatePlot(app);
        end

        % Value changed function: TimesEditField
        function updatedTimes(app, event)
            updatePlot(app);
        end

        % Value changed function: ScoresEditField
        function updatedScores(app, event)
            updatePlot(app);
        end

        % Value changing function: SpeedWeightSlider
        function updatingSpeedWeight(app, event)
            changingValue = event.Value;
            app.LengthWeightSlider.Value = 100 - changingValue;
        end

        % Value changing function: LengthWeightSlider
        function updatingLengthWeight(app, event)
            changingValue = event.Value;
            app.SpeedWeightSlider.Value = 100 - changingValue;
        end

        % Value changed function: KnownTimeEditField
        function updatedKnownTime(app, event)
            updatePlot(app);
        end

        % Value changed function: KnownLengthEditField
        function updatedKnownLength(app, event)
            updatePlot(app);
        end

        % Value changed function: KnownScoreEditField
        function updatedKnownScore(app, event)
            updatePlot(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {494, 494};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.HowtouseLabel = uilabel(app.GridLayout3);
            app.HowtouseLabel.Text = {'How to use:'; '1. Input known measurements'; '2. Input recorded times and scores'; '    (comma seperated)'; '    (does not matter if our own time and score'; '    is included)'; '3. Adjust the slider to favor increasing speed or'; '    increasing length'; '    (for example, a speed weight of 75 would'; '    assume a length weight of 25 and that'; '    increasing speed is 3x easier than'; '    increasing length'; 'Note:'; '    Time and length units do not matter as long'; '    as they are consistent'; ''};
            app.M3AnalysisToolLabel = uilabel(app.GridLayout3);
            app.M3AnalysisToolLabel.Text = 'M3 Analysis Tool';
            app.KnownLengthEditFieldLabel = uilabel(app.GridLayout3);
            app.KnownLengthEditFieldLabel.Text = 'Known Length';
            app.KnownLengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KnownLengthEditField.ValueChangedFcn = createCallbackFcn(app, @updatedKnownLength, true);
            app.KnownTimeEditFieldLabel = uilabel(app.GridLayout3);
            app.KnownTimeEditFieldLabel.Text = 'Known Time';
            app.KnownTimeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KnownTimeEditField.ValueChangedFcn = createCallbackFcn(app, @updatedKnownTime, true);
            app.KnownScoreEditFieldLabel = uilabel(app.GridLayout3);
            app.KnownScoreEditFieldLabel.Text = 'Known Score';
            app.KnownScoreEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KnownScoreEditField.ValueChangedFcn = createCallbackFcn(app, @updatedKnownScore, true);
            app.TimesEditFieldLabel = uilabel(app.GridLayout3);
            app.TimesEditFieldLabel.Text = 'Times';
            app.TimesEditField = uieditfield(app.GridLayout3, 'text');
            app.TimesEditField.ValueChangedFcn = createCallbackFcn(app, @updatedTimes, true);
            app.ScoresEditField = uieditfield(app.GridLayout3, 'text');
            app.ScoresEditField.ValueChangedFcn = createCallbackFcn(app, @updatedScores, true);
            app.ScoresEditFieldLabel = uilabel(app.GridLayout3);
            app.ScoresEditFieldLabel.Text = 'Scores';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'Speed (1 / Time)')
            ylabel(app.UIAxes, 'Length')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'JAD Logo.png';
            app.SpeedWeightSliderLabel = uilabel(app.GridLayout2);
            app.SpeedWeightSliderLabel.Text = 'Speed Weight';
            app.SpeedWeightSlider = uislider(app.GridLayout2);
            app.SpeedWeightSlider.ValueChangedFcn = createCallbackFcn(app, @updatedSpeedWeight, true);
            app.SpeedWeightSlider.ValueChangingFcn = createCallbackFcn(app, @updatingSpeedWeight, true);
            app.SpeedWeightSlider.Value = 50;
            app.LengthWeightSliderLabel = uilabel(app.GridLayout2);
            app.LengthWeightSliderLabel.Text = 'Length Weight';
            app.LengthWeightSlider = uislider(app.GridLayout2);
            app.LengthWeightSlider.ValueChangedFcn = createCallbackFcn(app, @updatedLengthWeight, true);
            app.LengthWeightSlider.ValueChangingFcn = createCallbackFcn(app, @updatingLengthWeight, true);
            app.LengthWeightSlider.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M3AnalysisTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end